classdef black_reference < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        SFR_RegionButtonGroup   matlab.ui.container.ButtonGroup
        ROIButton               matlab.ui.control.RadioButton
        FullButton              matlab.ui.control.RadioButton
        WidthEditFieldLabel     matlab.ui.control.Label
        WidthEditField          matlab.ui.control.NumericEditField
        heightEditFieldLabel    matlab.ui.control.Label
        heightEditField         matlab.ui.control.NumericEditField
        ListBox1                matlab.ui.control.ListBox
        Select_imageButton      matlab.ui.control.Button
        REditFieldLabel         matlab.ui.control.Label
        REditField              matlab.ui.control.NumericEditField
        GrEditFieldLabel        matlab.ui.control.Label
        GrEditField             matlab.ui.control.NumericEditField
        GbEditFieldLabel        matlab.ui.control.Label
        GbEditField             matlab.ui.control.NumericEditField
        BEditFieldLabel         matlab.ui.control.Label
        BEditField              matlab.ui.control.NumericEditField
        Black_LevelLabel        matlab.ui.control.Label
        BitdepthEditFieldLabel  matlab.ui.control.Label
        BitdepthEditField       matlab.ui.control.NumericEditField
    end


    properties (Access = private)
        file_path_name % Description
        main_handle_child
    end

    methods (Access = private)
       function bl=fullFrameMean(app, raw_image,bayer_order)
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                % This function ...
                %
                % Usage: calibrate_black_level_dev()
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                mask_bayer.r  = repmat(logical([1 0;0 0]),[size(raw_image,1)/2 size(raw_image,2)/2]);
                mask_bayer.gr = repmat(logical([0 1;0 0]),[size(raw_image,1)/2 size(raw_image,2)/2]);
                mask_bayer.gb = repmat(logical([0 0;1 0]),[size(raw_image,1)/2 size(raw_image,2)/2]);
                mask_bayer.b  = repmat(logical([0 0;0 1]),[size(raw_image,1)/2 size(raw_image,2)/2]);
                switch bayer_order
                    case '1' %,'rggb'}
                        mask_bayer.r  = repmat(logical([1 0;0 0]),[size(raw_image,1)/2 size(raw_image,2)/2]);
                        mask_bayer.gr = repmat(logical([0 1;0 0]),[size(raw_image,1)/2 size(raw_image,2)/2]);
                        mask_bayer.gb = repmat(logical([0 0;1 0]),[size(raw_image,1)/2 size(raw_image,2)/2]);
                        mask_bayer.b  = repmat(logical([0 0;0 1]),[size(raw_image,1)/2 size(raw_image,2)/2]);
                    case '2' %,'grbg'}
                        mask_bayer.gr = repmat(logical([1 0;0 0]),[size(raw_image,1)/2 size(raw_image,2)/2]);
                        mask_bayer.r  = repmat(logical([0 1;0 0]),[size(raw_image,1)/2 size(raw_image,2)/2]);
                        mask_bayer.b  = repmat(logical([0 0;1 0]),[size(raw_image,1)/2 size(raw_image,2)/2]);
                        mask_bayer.gb = repmat(logical([0 0;0 1]),[size(raw_image,1)/2 size(raw_image,2)/2]);
                    case '3' %,'gbrg'}
                        mask_bayer.gb = repmat(logical([1 0;0 0]),[size(raw_image,1)/2 size(raw_image,2)/2]);
                        mask_bayer.b  = repmat(logical([0 1;0 0]),[size(raw_image,1)/2 size(raw_image,2)/2]);
                        mask_bayer.r  = repmat(logical([0 0;1 0]),[size(raw_image,1)/2 size(raw_image,2)/2]);
                        mask_bayer.gr = repmat(logical([0 0;0 1]),[size(raw_image,1)/2 size(raw_image,2)/2]);
                    case '4' %,'bggr'}
                        mask_bayer.b  = repmat(logical([1 0;0 0]),[size(raw_image,1)/2 size(raw_image,2)/2]);
                        mask_bayer.gb = repmat(logical([0 1;0 0]),[size(raw_image,1)/2 size(raw_image,2)/2]);
                        mask_bayer.gr = repmat(logical([0 0;1 0]),[size(raw_image,1)/2 size(raw_image,2)/2]);
                        mask_bayer.r  = repmat(logical([0 0;0 1]),[size(raw_image,1)/2 size(raw_image,2)/2]);
                end
                mask_bayer.g = mask_bayer.gr|mask_bayer.gb;
                
                bl_r  = (mean2(raw_image(mask_bayer.r)));
                bl_gr = (mean2(raw_image(mask_bayer.gr)));
                bl_gb = (mean2(raw_image(mask_bayer.gb)));
                bl_b  = (mean2(raw_image(mask_bayer.b)));
                
                bl = single([bl_r bl_gr bl_gb bl_b]);
       end
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, main_handle)
            %app.file_path_name = [main_handle.path main_handle.file];
            %app.main_handle_child = main_handle;
        end

        % Button pushed function: Select_imageButton
        function Select_imageButtonPushed(app, event)
width =  app.WidthEditField.Value;
height = app.heightEditField.Value;
sfr_region = app.SFR_RegionButtonGroup.SelectedObject.Value;
bayer_oder = app.ListBox1.Value;

global PATH_NAME;
[filename, pathname, filterindex] = uigetfile( ...
       {'*.*',  'All Files (*.*)'}, ...
       'Pick a Image file', ...
        PATH_NAME);
if isequal(filename,0) || isequal(pathname,0)
    return;
end
PATH_NAME = pathname;


app.file_path_name = [pathname filename];
frame=imread_raw(app.file_path_name,width,height);
fullFrameMean(app,frame,bayer_oder);


if SFR_region == 1  %ROI
    [image_roi,block_coor]=roi_extract(image_Y);%用户自己选择区域
    [sfr_4]=sfrmat(image_roi);
    block_width=block_coor(3)-block_coor(1);%计算选取的块的宽和高
    block_height=block_coor(4)-block_coor(2);
    if block_width>block_height%横
        sfr_four_vertical=[];
        sfr_four_horiz=sfr_4;%四分之一处的值
    else
        sfr_four_vertical=sfr_4;
        sfr_four_horiz=[];
    end
    %figure(2);
    %imshow(image_Y);
    %title('灰度图');
    %rectangle('Position',[block_coor(1),block_coor(2),block_width,block_height],'edgecolor','b');hold on;%标出ROI区域
    %text(block_coor(3)+50,block_coor(4),num2str(sfr_4),'Color','red');hold on;
    excel_size=1;
else%ALL
    %%纵向提取并计算
    [minY_vertical,sfr_four_vertical,sfr_three_vertical]=minblock(image_Y,1);%找到方块，并提取右边部分，块的大小可更改函数minblock第43~64行程序,1为纵向，0为横向
    % %%横向提取并计算
    [minY_horiz,sfr_four_horiz,sfr_three_horiz]=minblock(image_Y,0);%找到方块，并提取右边部分，块的大小可更改函数minblock第43~64行程序,1为纵向，0为横向
    excel_size_tmp=size(minY_horiz);
    excel_size=excel_size_tmp(2);
end
gcf=figure(1);
% scrsz = get(0,'ScreenSize');%放大图像
% set(gcf,'Position',scrsz);
hgexport(gcf, '-clipboard'); %复制图像到剪贴板
% F=getframe(gcf);%?//获取整个窗口内容的图像
% imwrite(F.cdata,'result.jpg'); %保存图像
% printexcel(sfr_four_vertical,sfr_four_horiz,excel_size);%把数据写入EXCEL            

%showRGB(RGB); 
%  R=RGB(:,:,1);
% G=RGB(:,:,2);
% B=RGB(:,:,3);
% [Y,U,V]=RGB2YUV(R,G,B);
% Y=histeq(Y,256);%直方图均衡
% [R,G,B]=YUV2RGB(Y,U,V);
% RGB(:,:,1)=R;
% RGB(:,:,2)=G;
% RGB(:,:,3)=B;
% showRGB(RGB); 

% save_frame_show=save_frame_show';
% RGB= demosaic(save_frame_show,'rggb');%用MATLAB自带去马赛克函数
% figure(2)
% imshow(RGB);

% --- Executes on selection change in raw_type.
         
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.SFR_RegionButtonGroup = uibuttongroup(app.UIFigure);
            app.SFR_RegionButtonGroup.Title = 'SFR_Region';
            app.ROIButton = uiradiobutton(app.SFR_RegionButtonGroup);
            app.ROIButton.Text = 'ROI';
            app.FullButton = uiradiobutton(app.SFR_RegionButtonGroup);
            app.FullButton.Text = 'Full';
            app.FullButton.Value = true;
            app.WidthEditFieldLabel = uilabel(app.UIFigure);
            app.WidthEditFieldLabel.Text = 'Width';
            app.WidthEditField = uieditfield(app.UIFigure, 'numeric');
            app.heightEditFieldLabel = uilabel(app.UIFigure);
            app.heightEditFieldLabel.Text = 'height';
            app.heightEditField = uieditfield(app.UIFigure, 'numeric');
            app.ListBox1 = uilistbox(app.UIFigure);
            app.ListBox1.Items = {'BGbGrR', 'RGrGbB', 'GbBRGr', 'GrRBGb'};
            app.ListBox1.ItemsData = {'1', '2', '3', '4'};
            app.ListBox1.Value = '1';
            app.Select_imageButton = uibutton(app.UIFigure, 'push');
            app.Select_imageButton.ButtonPushedFcn = createCallbackFcn(app, @Select_imageButtonPushed, true);
            app.Select_imageButton.Text = 'Select_image';
            app.REditFieldLabel = uilabel(app.UIFigure);
            app.REditFieldLabel.Text = 'R';
            app.REditField = uieditfield(app.UIFigure, 'numeric');
            app.REditField.Editable = 'off';
            app.GrEditFieldLabel = uilabel(app.UIFigure);
            app.GrEditFieldLabel.Text = 'Gr';
            app.GrEditField = uieditfield(app.UIFigure, 'numeric');
            app.GrEditField.Editable = 'off';
            app.GbEditFieldLabel = uilabel(app.UIFigure);
            app.GbEditFieldLabel.Text = 'Gb';
            app.GbEditField = uieditfield(app.UIFigure, 'numeric');
            app.GbEditField.Editable = 'off';
            app.BEditFieldLabel = uilabel(app.UIFigure);
            app.BEditFieldLabel.Text = 'B';
            app.BEditField = uieditfield(app.UIFigure, 'numeric');
            app.BEditField.Editable = 'off';
            app.Black_LevelLabel = uilabel(app.UIFigure);
            app.Black_LevelLabel.Text = 'Black_Level';
            app.BitdepthEditFieldLabel = uilabel(app.UIFigure);
            app.BitdepthEditFieldLabel.Text = 'Bit depth';
            app.BitdepthEditField = uieditfield(app.UIFigure, 'numeric');
            app.BitdepthEditField.Value = 12;
        end
    end

    methods (Access = public)

        % Construct app
        function app = black_reference(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end