classdef illumination < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        sensitivityEditFieldLabel      matlab.ui.control.Label
        sensitivityEditField           matlab.ui.control.NumericEditField
        minintegrationtimeEditFieldLabel  matlab.ui.control.Label
        minintegrationtimeEditField    matlab.ui.control.NumericEditField
        maxilluminationEditFieldLabel  matlab.ui.control.Label
        maxilluminationEditField       matlab.ui.control.NumericEditField
        maxintegrationtimeEditFieldLabel  matlab.ui.control.Label
        maxintegrationtimeEditField    matlab.ui.control.NumericEditField
        FSDEditFieldLabel              matlab.ui.control.Label
        FSDEditField                   matlab.ui.control.NumericEditField
        minilluminationEditFieldLabel  matlab.ui.control.Label
        minilluminationEditField       matlab.ui.control.NumericEditField
        loaddarkimagethencalculateButton  matlab.ui.control.Button
        imagewidthEditFieldLabel       matlab.ui.control.Label
        imagewidthEditField            matlab.ui.control.NumericEditField
        imageheightEditFieldLabel      matlab.ui.control.Label
        imageheightEditField           matlab.ui.control.NumericEditField
        bitdepthEditFieldLabel         matlab.ui.control.Label
        bitdepthEditField              matlab.ui.control.NumericEditField
    end

    methods (Access = private)

        % Button pushed function: loaddarkimagethencalculateButton
        function loaddarkimagethencalculateButtonPushed(app, event)
            
            width =  app.imagewidthEditField.Value;
            height = app.imageheightEditField.Value;
            sensitivity = app.sensitivityEditField.Value;
            min_int_time = app.minintegrationtimeEditField.Value;
            max_int_time = app.maxintegrationtimeEditField.Value;
            RawBit = app.bitdepthEditField.Value;
            [file_dark,file_dark_path] = uigetfile;   
             fid = fopen([file_dark_path file_dark],'r');
            raw_dark = fread(fid,[width height],'ubit16=>uint16');
            
            raw_dark=raw_dark(1:width,:);
            noise_floor = mean2(raw_dark)+std2(raw_dark);
            Min_B = noise_floor/(2^RawBit*sensitivity*max_int_time);
            Max_B = 1/(sensitivity*min_int_time);
            app.maxilluminationEditField.Value = Max_B;
            app.minilluminationEditField.Value = Min_B;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.sensitivityEditFieldLabel = uilabel(app.UIFigure);
            app.sensitivityEditFieldLabel.Text = 'sensitivity';
            app.sensitivityEditField = uieditfield(app.UIFigure, 'numeric');
            app.sensitivityEditField.Value = 1000;
            app.minintegrationtimeEditFieldLabel = uilabel(app.UIFigure);
            app.minintegrationtimeEditFieldLabel.Text = 'min integration time';
            app.minintegrationtimeEditField = uieditfield(app.UIFigure, 'numeric');
            app.minintegrationtimeEditField.Value = 0.001;
            app.maxilluminationEditFieldLabel = uilabel(app.UIFigure);
            app.maxilluminationEditFieldLabel.Text = 'max illumination';
            app.maxilluminationEditField = uieditfield(app.UIFigure, 'numeric');
            app.maxilluminationEditField.Editable = 'off';
            app.maxintegrationtimeEditFieldLabel = uilabel(app.UIFigure);
            app.maxintegrationtimeEditFieldLabel.Text = 'max integration time';
            app.maxintegrationtimeEditField = uieditfield(app.UIFigure, 'numeric');
            app.maxintegrationtimeEditField.Value = 1;
            app.FSDEditFieldLabel = uilabel(app.UIFigure);
            app.FSDEditFieldLabel.Text = 'FSD';
            app.FSDEditField = uieditfield(app.UIFigure, 'numeric');
            app.FSDEditField.Value = 4095;
            app.minilluminationEditFieldLabel = uilabel(app.UIFigure);
            app.minilluminationEditFieldLabel.Text = 'min illumination';
            app.minilluminationEditField = uieditfield(app.UIFigure, 'numeric');
            app.minilluminationEditField.Editable = 'off';
            app.loaddarkimagethencalculateButton = uibutton(app.UIFigure, 'push');
            app.loaddarkimagethencalculateButton.ButtonPushedFcn = createCallbackFcn(app, @loaddarkimagethencalculateButtonPushed, true);
            app.loaddarkimagethencalculateButton.Text = 'load dark image then calculate';
            app.imagewidthEditFieldLabel = uilabel(app.UIFigure);
            app.imagewidthEditFieldLabel.Text = 'image width';
            app.imagewidthEditField = uieditfield(app.UIFigure, 'numeric');
            app.imagewidthEditField.Value = 1920;
            app.imageheightEditFieldLabel = uilabel(app.UIFigure);
            app.imageheightEditFieldLabel.Text = 'image height';
            app.imageheightEditField = uieditfield(app.UIFigure, 'numeric');
            app.imageheightEditField.Value = 1080;
            app.bitdepthEditFieldLabel = uilabel(app.UIFigure);
            app.bitdepthEditFieldLabel.Text = 'bit depth';
            app.bitdepthEditField = uieditfield(app.UIFigure, 'numeric');
            app.bitdepthEditField.Value = 12;
        end
    end

    methods (Access = public)

        % Construct app
        function app = illumination

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end