classdef perc_linearity < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        converterUIFigure         matlab.ui.Figure
        widthEditFieldLabel       matlab.ui.control.Label
        widthEditField            matlab.ui.control.NumericEditField
        heightEditFieldLabel      matlab.ui.control.Label
        heightEditField           matlab.ui.control.NumericEditField
        dataformatDropDownLabel   matlab.ui.control.Label
        dataformatDropDown        matlab.ui.control.DropDown
        bayerorderDropDownLabel   matlab.ui.control.Label
        bayerorderDropDown        matlab.ui.control.DropDown
        SNRButton                 matlab.ui.control.Button
        bitshiftDropDownLabel     matlab.ui.control.Label
        bitshiftDropDown          matlab.ui.control.DropDown
        calculatelinearityButton  matlab.ui.control.Button
        h_percentEditFieldLabel   matlab.ui.control.Label
        h_percentEditField        matlab.ui.control.NumericEditField
        v_percentEditFieldLabel   matlab.ui.control.Label
        v_percentEditField        matlab.ui.control.NumericEditField
    end


    properties (Access = private)
        file_path_name % Description
        main_handle_child
    end

    methods (Access = private)
    
%         function results = close_rawchild1(app)
%             app.main_handle_child.openrawButton.Enable = 'on';
%             results = 0;
%             delete(app)  
%                       
%         end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, main_handle)
           

        end

        % Button pushed function: SNRButton
        function SNRButtonPushed(app, event)
           % file1 = app.file;
 app.SNRButton.Enable = 'off';          
     
            FolderPath = uigetdir(pwd,'Set Work Path');
            if isequal(FolderPath,0)
               disp('User selected Cancel');
             app.SNRButton.Enable = 'on';
               delete(app)
            else
               disp(['User selected ', FolderPath]);
            end           
            img_dir = FolderPath;
        

           % addpath(genpath('.\'));
            
            imgNames = list_images(img_dir);                                            % Find all images in img_dir

            
            width =  app.widthEditField.Value;
            height = app.heightEditField.Value;
            HeightPercent = app.h_percentEditField.Value/100;
            WidthPercent = app.v_percentEditField.Value/100;
            endian = app.dataformatDropDown.Value;
            bayer_order = app.bayerorderDropDown.Value;
            bit_shift =str2double(app.bitshiftDropDown.Value);
            %          bit_shift = str2double(bit_shift);

          %  f=fopen(app.file_path_name); 
            HeightBorder = round((1-HeightPercent)*height/4);
            WidthBorder = round((1-WidthPercent)*width/4);

for img = 1:length(imgNames)
    
     filename=[img_dir filesep imgNames{img}];
     f=fopen(filename);
       
                frame =  fread(f,[width height],'ubit16=>uint16');
                raw = frame(1:width,:);


            fclose(f);
            frame=bitshift(frame,bit_shift);
                       
    if strcmpi(bayer_order,'rggb')
        Rmatrix = raw(1:2:end,1:2:end);
        GRmatrix = raw(1:2:end,2:2:end);
        GBmatrix = raw(2:2:end,1:2:end);
        Bmatrix = raw(2:2:end,2:2:end);
    elseif strcmpi(BayerOrder,'bggr')
        Bmatrix = raw(1:2:end,1:2:end);
        GBmatrix = raw(1:2:end,2:2:end);
        GRmatrix = raw(2:2:end,1:2:end);
        Rmatrix = raw(2:2:end,2:2:end);
    elseif strcmpi(BayerOrder,'bgrg')
        Bmatrix = raw(1:2:end,1:2:end);
        GBmatrix = raw(1:2:end,2:2:end);
        Rmatrix = raw(2:2:end,1:2:end);
        GRmatrix = raw(2:2:end,2:2:end);
    elseif strcmpi(BayerOrder,'grbg')
        GRmatrix = raw(1:2:end,1:2:end);
        Rmatrix = raw(1:2:end,2:2:end);
        Bmatrix = raw(2:2:end,1:2:end);
        GBmatrix = raw(2:2:end,2:2:end);
    end
    Rmatrix = Rmatrix(WidthBorder+1:end-WidthBorder,HeightBorder+1:end-HeightBorder);
    Rvalue(img) = uint16(mean(Rmatrix(:)));
    RNoise(img) = std(single(Rmatrix(:)));
    R_SNR(img) = single(Rvalue(img))/single(RNoise(img));
    GRmatrix = GRmatrix(WidthBorder+1:end-WidthBorder,HeightBorder+1:end-HeightBorder);
    GRvalue(img) = uint16(mean(GRmatrix(:)));
    GRNoise(img) = std(single(GRmatrix(:)));
    GR_SNR(img) = single(GRvalue(img))/single(GRNoise(img));
    GBmatrix = GBmatrix(WidthBorder+1:end-WidthBorder,HeightBorder+1:end-HeightBorder);    
    GBvalue(img) = uint16(mean(GBmatrix(:)));
    GBNoise(img) = std(single(GBmatrix(:)));
    GB_SNR(img) = single(GBvalue(img))/single(GBNoise(img));
    
    Bmatrix = Bmatrix(WidthBorder+1:end-WidthBorder,HeightBorder+1:end-HeightBorder);
    Bvalue(img) =uint16( mean(Bmatrix(:)));
    BNoise(img) = std(single(Bmatrix(:)));    
    B_SNR(img) = single(Bvalue(img))/single(BNoise(img));
    
    
end
%shift uint16 to uint10
Rvalue =  bitshift(Rvalue,-2);
GRvalue =  bitshift(GRvalue,-2);
GBvalue =  bitshift(GBvalue,-2);
Bvalue =  bitshift(Bvalue,-2);

    figure(1);
    plot(R_SNR,'r');
    hold on;
    
    figure(2);
  
    plot(GR_SNR,'g');
    hold on;
    figure(3);
   

    plot(GB_SNR,'c');
    hold on;
    figure(4);
   
    plot(B_SNR,'b');
     hold on
   
          app.SNRButton.Enable = 'on'; 
        end

        % Close request function: converterUIFigure
        function converterUIFigureCloseRequest(app, event)
%             app.Raw2Plain16Button.Enable = 'off';
        
        app.main_handle_child.Raw2Plain16Button.Enable = 'on';
        delete(app)          
            
        end

        % Button pushed function: calculatelinearityButton
        function calculatelinearityButtonPushed(app, event)
            app.calculatelinearityButton.Enable = 'off';
            FolderPath = uigetdir(pwd,'Set Work Path');
            if isequal(FolderPath,0)
               disp('User selected Cancel');
              app.calculatelinearityButton.Enable = 'on';
               delete(app)
            else
               disp(['User selected ', FolderPath]);
            end           
            img_dir = FolderPath;
        

           % addpath(genpath('.\'));
            
            imgNames = list_images(img_dir);                                            % Find all images in img_dir

            
            width =  app.widthEditField.Value;
            height = app.heightEditField.Value;
            HeightPercent = app.h_percentEditField.Value/100;
            WidthPercent = app.v_percentEditField.Value/100;
            endian = app.dataformatDropDown.Value;
            bayer_order = app.bayerorderDropDown.Value;
            bit_shift =str2double(app.bitshiftDropDown.Value);
            %          bit_shift = str2double(bit_shift);

          %  f=fopen(app.file_path_name); 
            HeightBorder = round((1-HeightPercent)*height/4);
            WidthBorder = round((1-WidthPercent)*width/4);

for img = 1:length(imgNames)
    
     filename=[img_dir filesep imgNames{img}];
     f=fopen(filename);
       
                frame =  fread(f,[width height],'ubit16=>uint16');
                raw = frame(1:width,:);


            fclose(f);
            frame=bitshift(frame,bit_shift);
                       
    if strcmpi(bayer_order,'rggb')
        Rmatrix = raw(1:2:end,1:2:end);
        GRmatrix = raw(1:2:end,2:2:end);
        GBmatrix = raw(2:2:end,1:2:end);
        Bmatrix = raw(2:2:end,2:2:end);
    elseif strcmpi(BayerOrder,'bggr')
        Bmatrix = raw(1:2:end,1:2:end);
        GBmatrix = raw(1:2:end,2:2:end);
        GRmatrix = raw(2:2:end,1:2:end);
        Rmatrix = raw(2:2:end,2:2:end);
    elseif strcmpi(BayerOrder,'bgrg')
        Bmatrix = raw(1:2:end,1:2:end);
        GBmatrix = raw(1:2:end,2:2:end);
        Rmatrix = raw(2:2:end,1:2:end);
        GRmatrix = raw(2:2:end,2:2:end);
    elseif strcmpi(BayerOrder,'grbg')
        GRmatrix = raw(1:2:end,1:2:end);
        Rmatrix = raw(1:2:end,2:2:end);
        Bmatrix = raw(2:2:end,1:2:end);
        GBmatrix = raw(2:2:end,2:2:end);
    end
    Rmatrix = Rmatrix(WidthBorder+1:end-WidthBorder,HeightBorder+1:end-HeightBorder);
    Rvalue(img) = uint16(mean(Rmatrix(:)));
    RNoise(img) = std(single(Rmatrix(:)));
    R_SNR(img) = single(Rvalue(img))/single(RNoise(img));
    GRmatrix = GRmatrix(WidthBorder+1:end-WidthBorder,HeightBorder+1:end-HeightBorder);
    GRvalue(img) = uint16(mean(GRmatrix(:)));
    GRNoise(img) = std(single(GRmatrix(:)));
    GR_SNR(img) = single(GRvalue(img))/single(GRNoise(img));
    GBmatrix = GBmatrix(WidthBorder+1:end-WidthBorder,HeightBorder+1:end-HeightBorder);    
    GBvalue(img) = uint16(mean(GBmatrix(:)));
    GBNoise(img) = std(single(GBmatrix(:)));
    GB_SNR(img) = single(GBvalue(img))/single(GBNoise(img));
    
    Bmatrix = Bmatrix(WidthBorder+1:end-WidthBorder,HeightBorder+1:end-HeightBorder);
    Bvalue(img) =uint16( mean(Bmatrix(:)));
    BNoise(img) = std(single(Bmatrix(:)));    
    B_SNR(img) = single(Bvalue(img))/single(BNoise(img));
    
    
end
%shift uint16 to uint10
Rvalue =  bitshift(Rvalue,-2);
GRvalue =  bitshift(GRvalue,-2);
GBvalue =  bitshift(GBvalue,-2);
Bvalue =  bitshift(Bvalue,-2);

    figure(1);
    plot(Rvalue,'r');
    hold on;
    
    figure(2);
  
    plot(GRvalue,'g');
    hold on;
    figure(3);
   

    plot(GBvalue,'c');
    hold on;
    figure(4);
   
    plot(GBvalue,'b');
     hold on
    app.calculatelinearityButton.Enable = 'on';


        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.converterUIFigure = uifigure;
            app.converterUIFigure.Name = 'converter';
            app.converterUIFigure.CloseRequestFcn = createCallbackFcn(app, @converterUIFigureCloseRequest, true);
            app.widthEditFieldLabel = uilabel(app.converterUIFigure);
            app.widthEditFieldLabel.Text = 'width';
            app.widthEditField = uieditfield(app.converterUIFigure, 'numeric');
            app.widthEditField.Value = 1280;
            app.heightEditFieldLabel = uilabel(app.converterUIFigure);
            app.heightEditFieldLabel.Text = 'height';
            app.heightEditField = uieditfield(app.converterUIFigure, 'numeric');
            app.heightEditField.Value = 1080;
            app.dataformatDropDownLabel = uilabel(app.converterUIFigure);
            app.dataformatDropDownLabel.Text = 'data format';
            app.dataformatDropDown = uidropdown(app.converterUIFigure);
            app.dataformatDropDown.Items = {'ieee-le', 'ieee-be'};
            app.dataformatDropDown.Value = 'ieee-le';
            app.bayerorderDropDownLabel = uilabel(app.converterUIFigure);
            app.bayerorderDropDownLabel.Text = 'bayer order';
            app.bayerorderDropDown = uidropdown(app.converterUIFigure);
            app.bayerorderDropDown.Items = {'rggb', 'grbr', 'gbrg', 'grbg'};
            app.bayerorderDropDown.Value = 'rggb';
            app.SNRButton = uibutton(app.converterUIFigure, 'push');
            app.SNRButton.ButtonPushedFcn = createCallbackFcn(app, @SNRButtonPushed, true);
            app.SNRButton.Text = 'SNR';
            app.bitshiftDropDownLabel = uilabel(app.converterUIFigure);
            app.bitshiftDropDownLabel.Text = 'bit shift';
            app.bitshiftDropDown = uidropdown(app.converterUIFigure);
            app.bitshiftDropDown.Items = {'0', '-2', '-4', '-8', '2', '4', '8'};
            app.bitshiftDropDown.Value = '-4';
            app.calculatelinearityButton = uibutton(app.converterUIFigure, 'push');
            app.calculatelinearityButton.ButtonPushedFcn = createCallbackFcn(app, @calculatelinearityButtonPushed, true);
            app.calculatelinearityButton.Text = 'calculate linearity';
            app.h_percentEditFieldLabel = uilabel(app.converterUIFigure);
            app.h_percentEditFieldLabel.Text = 'h_percent';
            app.h_percentEditField = uieditfield(app.converterUIFigure, 'numeric');
            app.h_percentEditField.Value = 10;
            app.v_percentEditFieldLabel = uilabel(app.converterUIFigure);
            app.v_percentEditFieldLabel.Text = 'v_percent';
            app.v_percentEditField = uieditfield(app.converterUIFigure, 'numeric');
            app.v_percentEditField.Value = 10;
        end
    end

    methods (Access = public)

        % Construct app
        function app = perc_linearity(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.converterUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.converterUIFigure)
        end
    end
end