classdef raw2plain16 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        converterUIFigure          matlab.ui.Figure
        widthEditFieldLabel        matlab.ui.control.Label
        widthEditField             matlab.ui.control.NumericEditField
        heightEditFieldLabel       matlab.ui.control.Label
        heightEditField            matlab.ui.control.NumericEditField
        pixeloffsetEditFieldLabel  matlab.ui.control.Label
        pixeloffsetEditField       matlab.ui.control.NumericEditField
        inputformatDropDownLabel   matlab.ui.control.Label
        inputformatDropDown        matlab.ui.control.DropDown
        outputformatDropDownLabel  matlab.ui.control.Label
        outputformatDropDown       matlab.ui.control.DropDown
        dataformatDropDownLabel    matlab.ui.control.Label
        dataformatDropDown         matlab.ui.control.DropDown
        bayerorderDropDownLabel    matlab.ui.control.Label
        bayerorderDropDown         matlab.ui.control.DropDown
        SingleFileProcessButton    matlab.ui.control.Button
        bitshiftDropDownLabel      matlab.ui.control.Label
        bitshiftDropDown           matlab.ui.control.DropDown
        digitalgainEditFieldLabel  matlab.ui.control.Label
        digitalgainEditField       matlab.ui.control.NumericEditField
        FolderProcessButton        matlab.ui.control.Button
    end


    properties (Access = private)
        file_path_name % Description
        main_handle_child
    end

    methods (Access = private)
    
%         function results = close_rawchild1(app)
%             app.main_handle_child.openrawButton.Enable = 'on';
%             results = 0;
%             delete(app)  
%                       
%         end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, main_handle)
            app.file_path_name = [main_handle.path main_handle.file];
            app.main_handle_child = main_handle;

        end

        % Button pushed function: SingleFileProcessButton
        function SingleFileProcessButtonPushed(app, event)
           % file1 = app.file;
 app.SingleFileProcessButton.Enable = 'off';          
  [file,path] = uigetfile({'*.raw';'*.RAW'});
if isequal(file,0)
   disp('User selected Cancel');
   app.main_handle_child.Raw2Plain16Button.Enable = 'on';
   delete(app)
else
   disp(['User selected ', fullfile(path,file)]);
end
            img_dir_out = [path '_processed' filesep ];
            fprintf('img_dir_out %s\n', img_dir_out);
            if exist(img_dir_out, 'dir') ~= 7
            mkdir(img_dir_out);
            end
            
           
            width =  app.widthEditField.Value;
            height = app.heightEditField.Value;
            pixel_offset = app.pixeloffsetEditField.Value;
            input_format = app.inputformatDropDown.Value;
            output_format = app.outputformatDropDown.Value;
            endian = app.dataformatDropDown.Value;
            bayer_order = app.bayerorderDropDown.Value;
            bit_shift =str2double(app.bitshiftDropDown.Value);
            %          bit_shift = str2double(bit_shift);
            app.file_path_name = [path file];
            f=fopen(app.file_path_name);
            if app.main_handle_child.rawtypeDropDown.Value == "customer_raw"
                %example code
                fread(f, pixel_offset);        
                frame =  fread(f,[width height],'ubit16=>uint16',endian);
                frame = frame(1:width,:)';
                
            elseif app.main_handle_child.rawtypeDropDown.Value == "raw_plain_16"
                frame =  fread(f,[width height],'ubit16=>uint16');
                frame = frame(1:width,:)';

            
            elseif app.main_handle_child.rawtypeDropDown.Value == "mipi_raw10"

                FRAME = read_MIPI_10bit(app.file_path_name,width,height);
                frame = convert_MIPI10toMIPI_8bit_vector(FRAME,width,height);  


            elseif app.main_handle_child.rawtypeDropDown.Value == "mipi_raw8"
        
             frame = read_MIPI_8bit(app.file_path_name,width,height);


            elseif app.main_handle_child.rawtypeDropDown.Value == "dng"
                fread(f, pixel_offset);             
                frame =  fread(f,[width height],'ubit16=>uint16',endian);
                
            else
                fread(f, pixel_offset);             
                frame =  fread(f,[width height],'ubit16=>uint16',endian);
            end
            fclose(f);
            frame=bitshift(frame,bit_shift);
            
            frame = frame.*app.digitalgainEditField.Value;
            
            
            fname = file;
            fnout = [img_dir_out fname(1:end-4) '_processed_' '.raw'];
            frame = uint16(frame)';
            fid = fopen(fnout,'w');
            fwrite(fid,frame,'uint16');
            fclose(fid);
          app.SingleFileProcessButton.Enable = 'on'; 
        end

        % Close request function: converterUIFigure
        function converterUIFigureCloseRequest(app, event)
%             app.Raw2Plain16Button.Enable = 'off';
        
        app.main_handle_child.Raw2Plain16Button.Enable = 'on';
        delete(app)          
            
        end

        % Button pushed function: FolderProcessButton
        function FolderProcessButtonPushed(app, event)
            app.FolderProcessButton.Enable = 'off';
            FolderPath = uigetdir(pwd,'Set Work Path');
 if isequal(FolderPath,0)
   disp('User selected Cancel');
   app.main_handle_child.Raw2Plain16Button.Enable = 'on';
   delete(app)
else
   disp(['User selected ', FolderPath]);
end           
            img_dir = FolderPath;
            img_dir_out = [img_dir '_processed' filesep ];
            fprintf('img_dir_out %s\n', img_dir_out);
            if exist(img_dir_out, 'dir') ~= 7
                mkdir(img_dir_out);
            end
           % addpath(genpath('.\'));
            
            imgNames = list_images(img_dir);                                            % Find all images in img_dir

            
            width =  app.widthEditField.Value;
            height = app.heightEditField.Value;
            pixel_offset = app.pixeloffsetEditField.Value;
            input_format = app.inputformatDropDown.Value;
            output_format = app.outputformatDropDown.Value;
            endian = app.dataformatDropDown.Value;
            bayer_order = app.bayerorderDropDown.Value;
            bit_shift =str2double(app.bitshiftDropDown.Value);
            %          bit_shift = str2double(bit_shift);

          %  f=fopen(app.file_path_name); 


for img = 1:length(imgNames)
    
    filename=[img_dir filesep imgNames{img}];
    f=fopen(filename);
                if app.main_handle_child.rawtypeDropDown.Value == "a_raw"
                fread(f, pixel_offset);        
                frame =  fread(f,[width*2 height],'ubit16=>uint16',endian);
                frame = frame(1:width,:)';
                
            elseif app.main_handle_child.rawtypeDropDown.Value == "raw_plain_16"
                frame =  fread(f,[width height],'ubit16=>uint16');
                frame = frame(1:width,:)';
%                 FRAME = read_MIPI_10bit(app.file_path_name,width,height);
%                 frame = convert_MIPI10toMIPI_8bit_vector(FRAME,width,height);    
            
            elseif app.main_handle_child.rawtypeDropDown.Value == "mipi_raw10"

                FRAME = read_MIPI_10bit(app.file_path_name,width,height);
                frame = convert_MIPI10toMIPI_8bit_vector(FRAME,width,height);  
                    
                
            elseif app.main_handle_child.rawtypeDropDown.Value == "mipi_raw8"
        
                FRAME = read_MIPI_10bit(app.file_path_name,width,height);
                frame = convert_MIPI10toMIPI_8bit_vector(FRAME,width,height);  


            elseif app.main_handle_child.rawtypeDropDown.Value == "dng"
                fread(f, pixel_offset);             
                frame =  fread(f,[width height],'ubit16=>uint16',endian);
                
            else
                fread(f, pixel_offset);             
                frame =  fread(f,[width height],'ubit16=>uint16',endian);
            end
            fclose(f);
            frame=bitshift(frame,bit_shift);
            
            frame = frame.*app.digitalgainEditField.Value;
            fname = imgNames{img};
            fnout = [img_dir_out fname(1:end-4) '_processed_' '.raw'];
            frame = uint16(frame)';
            fid = fopen(fnout,'w');
            fwrite(fid,frame,'uint16');
            fclose(fid);
            
end   
            app.FolderProcessButton.Enable = 'on';

        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.converterUIFigure = uifigure;
            app.converterUIFigure.Name = 'converter';
            app.converterUIFigure.CloseRequestFcn = createCallbackFcn(app, @converterUIFigureCloseRequest, true);
            app.widthEditFieldLabel = uilabel(app.converterUIFigure);
            app.widthEditFieldLabel.Text = 'width';
            app.widthEditField = uieditfield(app.converterUIFigure, 'numeric');
            app.widthEditField.Value = 1920;
            app.heightEditFieldLabel = uilabel(app.converterUIFigure);
            app.heightEditFieldLabel.Text = 'height';
            app.heightEditField = uieditfield(app.converterUIFigure, 'numeric');
            app.heightEditField.Value = 1080;
            app.pixeloffsetEditFieldLabel = uilabel(app.converterUIFigure);
            app.pixeloffsetEditFieldLabel.Text = 'pixel offset';
            app.pixeloffsetEditField = uieditfield(app.converterUIFigure, 'numeric');
            app.pixeloffsetEditField.Value = 30;
            app.inputformatDropDownLabel = uilabel(app.converterUIFigure);
            app.inputformatDropDownLabel.Text = 'input format';
            app.inputformatDropDown = uidropdown(app.converterUIFigure);
            app.inputformatDropDown.Items = {'uint8', 'uint16', 'uint32'};
            app.inputformatDropDown.Value = 'uint16';
            app.outputformatDropDownLabel = uilabel(app.converterUIFigure);
            app.outputformatDropDownLabel.Text = 'output format';
            app.outputformatDropDown = uidropdown(app.converterUIFigure);
            app.outputformatDropDown.Items = {'uint8', 'uint16', 'uint32'};
            app.outputformatDropDown.Value = 'uint16';
            app.dataformatDropDownLabel = uilabel(app.converterUIFigure);
            app.dataformatDropDownLabel.Text = 'data format';
            app.dataformatDropDown = uidropdown(app.converterUIFigure);
            app.dataformatDropDown.Items = {'ieee-le', 'ieee-be'};
            app.dataformatDropDown.Value = 'ieee-le';
            app.bayerorderDropDownLabel = uilabel(app.converterUIFigure);
            app.bayerorderDropDownLabel.Text = 'bayer order';
            app.bayerorderDropDown = uidropdown(app.converterUIFigure);
            app.bayerorderDropDown.Items = {'rggb', 'grbr', 'gbrg', 'grbg'};
            app.bayerorderDropDown.Value = 'rggb';
            app.SingleFileProcessButton = uibutton(app.converterUIFigure, 'push');
            app.SingleFileProcessButton.ButtonPushedFcn = createCallbackFcn(app, @SingleFileProcessButtonPushed, true);
            app.SingleFileProcessButton.Text = 'Single File Process';
            app.bitshiftDropDownLabel = uilabel(app.converterUIFigure);
            app.bitshiftDropDownLabel.Text = 'bit shift';
            app.bitshiftDropDown = uidropdown(app.converterUIFigure);
            app.bitshiftDropDown.Items = {'0', '-2', '-4', '-8', '2', '4', '8'};
            app.bitshiftDropDown.Value = '-4';
            app.digitalgainEditFieldLabel = uilabel(app.converterUIFigure);
            app.digitalgainEditFieldLabel.Text = 'digital gain';
            app.digitalgainEditField = uieditfield(app.converterUIFigure, 'numeric');
            app.digitalgainEditField.ValueDisplayFormat = '%5.2f';
            app.digitalgainEditField.Value = 1;
            app.FolderProcessButton = uibutton(app.converterUIFigure, 'push');
            app.FolderProcessButton.ButtonPushedFcn = createCallbackFcn(app, @FolderProcessButtonPushed, true);
            app.FolderProcessButton.Text = 'Folder Process';
        end
    end

    methods (Access = public)

        % Construct app
        function app = raw2plain16(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.converterUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.converterUIFigure)
        end
    end
end