classdef raw_child1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        rawviewerUIFigure          matlab.ui.Figure
        widthEditFieldLabel        matlab.ui.control.Label
        widthEditField             matlab.ui.control.NumericEditField
        heightEditFieldLabel       matlab.ui.control.Label
        heightEditField            matlab.ui.control.NumericEditField
        pixeloffsetEditFieldLabel  matlab.ui.control.Label
        pixeloffsetEditField       matlab.ui.control.NumericEditField
        inputformatDropDownLabel   matlab.ui.control.Label
        inputformatDropDown        matlab.ui.control.DropDown
        outputformatDropDownLabel  matlab.ui.control.Label
        outputformatDropDown       matlab.ui.control.DropDown
        dataformatDropDownLabel    matlab.ui.control.Label
        dataformatDropDown         matlab.ui.control.DropDown
        bayerorderDropDownLabel    matlab.ui.control.Label
        bayerorderDropDown         matlab.ui.control.DropDown
        viewrawfileButton          matlab.ui.control.Button
        bitshiftDropDownLabel      matlab.ui.control.Label
        bitshiftDropDown           matlab.ui.control.DropDown
        digitalgainEditFieldLabel  matlab.ui.control.Label
        digitalgainEditField       matlab.ui.control.NumericEditField
        splitviewrawfileButton     matlab.ui.control.Button
    end


    properties (Access = private)
        file_path_name % Description
        main_handle_child
    end

    methods (Access = private)
    
        function results = close_rawchild1(app)
            app.main_handle_child.openrawButton.Enable = 'on';
            results = 0;
            delete(app)  
                      
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, main_handle)
            app.file_path_name = [main_handle.path main_handle.file];
            app.main_handle_child = main_handle;
%             app.main_handle_child.openrawButton.Enable = 'off';
        end

        % Button pushed function: viewrawfileButton
        function viewrawfileButtonPushed(app, event)
           % file1 = app.file;
            
            width =  app.widthEditField.Value;
            height = app.heightEditField.Value;
            pixel_offset = app.pixeloffsetEditField.Value;
            input_format = app.inputformatDropDown.Value;
            output_format = app.outputformatDropDown.Value;
            endian = app.dataformatDropDown.Value;
            bayer_order = app.bayerorderDropDown.Value;
            bit_shift =str2double(app.bitshiftDropDown.Value);
            %          bit_shift = str2double(bit_shift);

            f=fopen(app.file_path_name); 


            if app.main_handle_child.rawformatDropDown.Value == "customer_raw"
                %example code
                fread(f, pixel_offset);        
                frame =  fread(f,[width height],'ubit16=>uint16',endian);
                frame = frame(1:width,:)';
                
            elseif app.main_handle_child.rawformatDropDown.Value == "raw_plain_16"
                frame =  fread(f,[width height],'ubit16=>uint16');
                frame = frame(1:width,:)';
%                 FRAME = read_MIPI_10bit(app.file_path_name,width,height);
%                 frame = convert_MIPI10toMIPI_8bit_vector(FRAME,width,height);    
            
            elseif app.main_handle_child.rawformatDropDown.Value == "mipi_raw10"

                FRAME = read_MIPI_10bit(app.file_path_name,width,height);
                frame = convert_MIPI10toMIPI_8bit_vector(FRAME,width,height);  
                    
                
            elseif app.main_handle_child.rawformatDropDown.Value == "mipi_raw8"
              frame = read_MIPI_8bit(app.file_path_name,width,height);


            elseif app.main_handle_child.rawformatDropDown.Value == "dng"
                fread(f, pixel_offset);             
                frame =  fread(f,[width height],'ubit16=>uint16',endian);
                
            else
                fread(f, pixel_offset);             
                frame =  fread(f,[width height],'ubit16=>uint16',endian);
            end
            fclose(f);
            %frame=bitshift(frame,bit_shift);
            
            frame = frame.*app.digitalgainEditField.Value;
            imtool(frame,[0,2^(16+bit_shift)-1]);
            close_rawchild1(app);

        end

        % Close request function: rawviewerUIFigure
        function rawviewerUIFigureCloseRequest(app, event)
            app.main_handle_child.openrawButton.Enable = 'on';
            delete(app)
            
            
        end

        % Button pushed function: splitviewrawfileButton
        function splitviewrawfileButtonPushed(app, event)
            width =  app.widthEditField.Value;
            height = app.heightEditField.Value;
            pixel_offset = app.pixeloffsetEditField.Value;
            input_format = app.inputformatDropDown.Value;
            output_format = app.outputformatDropDown.Value;
            endian = app.dataformatDropDown.Value;
            bayer_order = app.bayerorderDropDown.Value;
            bit_shift =str2double(app.bitshiftDropDown.Value);
            %          bit_shift = str2double(bit_shift);

            f=fopen(app.file_path_name); 


            if app.main_handle_child.rawformatDropDown.Value == "a_raw"
                fread(f, pixel_offset);        
                frame =  fread(f,[width*2 height],'ubit16=>uint16',endian);
                frame = frame(1:width,:)';
                
            elseif app.main_handle_child.rawformatDropDown.Value == "raw_plain_16"
                frame =  fread(f,[width height],'ubit16=>uint16');
                frame = frame(1:width,:)';
%                 FRAME = read_MIPI_10bit(app.file_path_name,width,height);
%                 frame = convert_MIPI10toMIPI_8bit_vector(FRAME,width,height);    
            
            elseif app.main_handle_child.rawformatDropDown.Value == "mipi_raw10"

                FRAME = read_MIPI_10bit(app.file_path_name,width,height);
                frame = convert_MIPI10toMIPI_8bit_vector(FRAME,width,height);  
                    
                
            elseif app.main_handle_child.rawformatDropDown.Value == "mipi_raw8"
        
                FRAME = read_MIPI_10bit(app.file_path_name,width,height);
                frame = convert_MIPI10toMIPI_8bit_vector(FRAME,width,height);  


            elseif app.main_handle_child.rawformatDropDown.Value == "dng"
                fread(f, pixel_offset);             
                frame =  fread(f,[width height],'ubit16=>uint16',endian);
                
            else
                fread(f, pixel_offset);             
                frame =  fread(f,[width height],'ubit16=>uint16',endian);
            end
            fclose(f);
            %frame=bitshift(frame,bit_shift);
            
            frame = frame.*app.digitalgainEditField.Value;
            [R,Gr,Gb,B] = splitBayer(frame,bayer_order);
            %figure(1);
            hFigure = imtool(frame,[0,2^(16+bit_shift)-1]);
            set(hFigure,'NumberTitle','off','Name','R channel');
            %figure(2);
            hFigure =imtool(frame,[0,2^(16+bit_shift)-1]);
            set(hFigure,'NumberTitle','off','Name','Gr channel');
            %figure(3);
            hFigure =imtool(frame,[0,2^(16+bit_shift)-1]);
            set(hFigure,'NumberTitle','off','Name','Gb channel');
            %figure(4);
            hFigure =imtool(frame,[0,2^(16+bit_shift)-1]);
            set(hFigure,'NumberTitle','off','Name','B channel');
            close_rawchild1(app);            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.rawviewerUIFigure = uifigure;
            app.rawviewerUIFigure.Name = 'raw viewer';
            app.rawviewerUIFigure.CloseRequestFcn = createCallbackFcn(app, @rawviewerUIFigureCloseRequest, true);
            app.widthEditFieldLabel = uilabel(app.rawviewerUIFigure);
            app.widthEditFieldLabel.Text = 'width';
            app.widthEditField = uieditfield(app.rawviewerUIFigure, 'numeric');
            app.widthEditField.Value = 1920;
            app.heightEditFieldLabel = uilabel(app.rawviewerUIFigure);
            app.heightEditFieldLabel.Text = 'height';
            app.heightEditField = uieditfield(app.rawviewerUIFigure, 'numeric');
            app.heightEditField.Value = 1080;
            app.pixeloffsetEditFieldLabel = uilabel(app.rawviewerUIFigure);
            app.pixeloffsetEditFieldLabel.Text = 'pixel offset';
            app.pixeloffsetEditField = uieditfield(app.rawviewerUIFigure, 'numeric');
            app.inputformatDropDownLabel = uilabel(app.rawviewerUIFigure);
            app.inputformatDropDownLabel.Text = 'input format';
            app.inputformatDropDown = uidropdown(app.rawviewerUIFigure);
            app.inputformatDropDown.Items = {'uint8', 'uint16', 'uint32'};
            app.inputformatDropDown.Value = 'uint16';
            app.outputformatDropDownLabel = uilabel(app.rawviewerUIFigure);
            app.outputformatDropDownLabel.Text = 'output format';
            app.outputformatDropDown = uidropdown(app.rawviewerUIFigure);
            app.outputformatDropDown.Items = {'uint8', 'uint16', 'uint32'};
            app.outputformatDropDown.Value = 'uint16';
            app.dataformatDropDownLabel = uilabel(app.rawviewerUIFigure);
            app.dataformatDropDownLabel.Text = 'data format';
            app.dataformatDropDown = uidropdown(app.rawviewerUIFigure);
            app.dataformatDropDown.Items = {'ieee-le', 'ieee-be'};
            app.dataformatDropDown.Value = 'ieee-le';
            app.bayerorderDropDownLabel = uilabel(app.rawviewerUIFigure);
            app.bayerorderDropDownLabel.Text = 'bayer order';
            app.bayerorderDropDown = uidropdown(app.rawviewerUIFigure);
            app.bayerorderDropDown.Items = {'rggb', 'grbr', 'gbrg', 'grbg'};
            app.bayerorderDropDown.Value = 'rggb';
            app.viewrawfileButton = uibutton(app.rawviewerUIFigure, 'push');
            app.viewrawfileButton.ButtonPushedFcn = createCallbackFcn(app, @viewrawfileButtonPushed, true);
            app.viewrawfileButton.Text = 'view raw file';
            app.bitshiftDropDownLabel = uilabel(app.rawviewerUIFigure);
            app.bitshiftDropDownLabel.Text = 'bit shift';
            app.bitshiftDropDown = uidropdown(app.rawviewerUIFigure);
            app.bitshiftDropDown.Items = {'0', '-2', '-4', '-8', '2', '4', '8'};
            app.bitshiftDropDown.Value = '0';
            app.digitalgainEditFieldLabel = uilabel(app.rawviewerUIFigure);
            app.digitalgainEditFieldLabel.Text = 'digital gain';
            app.digitalgainEditField = uieditfield(app.rawviewerUIFigure, 'numeric');
            app.digitalgainEditField.ValueDisplayFormat = '%5.2f';
            app.digitalgainEditField.Value = 1;
            app.splitviewrawfileButton = uibutton(app.rawviewerUIFigure, 'push');
            app.splitviewrawfileButton.ButtonPushedFcn = createCallbackFcn(app, @splitviewrawfileButtonPushed, true);
            app.splitviewrawfileButton.Text = 'split view raw file';
        end
    end

    methods (Access = public)

        % Construct app
        function app = raw_child1(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.rawviewerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.rawviewerUIFigure)
        end
    end
end