classdef sensitivity < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        image_illuminatedButton      matlab.ui.control.Button
        image_darkButton             matlab.ui.control.Button
        BEditFieldLabel              matlab.ui.control.Label
        BEditField                   matlab.ui.control.NumericEditField
        T_intEditFieldLabel          matlab.ui.control.Label
        T_intEditField               matlab.ui.control.NumericEditField
        sensitivityEditFieldLabel    matlab.ui.control.Label
        sensitivityEditField         matlab.ui.control.NumericEditField
        imagewidthEditFieldLabel     matlab.ui.control.Label
        imagewidthEditField          matlab.ui.control.NumericEditField
        imageheightEditFieldLabel    matlab.ui.control.Label
        imageheightEditField         matlab.ui.control.NumericEditField
        calculateButton              matlab.ui.control.Button
        bitdepthEditFieldLabel       matlab.ui.control.Label
        bitdepthEditField            matlab.ui.control.EditField
        widthpercentEditFieldLabel   matlab.ui.control.Label
        widthpercentEditField        matlab.ui.control.NumericEditField
        bayerorderEditFieldLabel     matlab.ui.control.Label
        bayerorderEditField          matlab.ui.control.EditField
        heightpercentEditFieldLabel  matlab.ui.control.Label
        heightpercentEditField       matlab.ui.control.NumericEditField
        FSDEditFieldLabel            matlab.ui.control.Label
        FSDEditField                 matlab.ui.control.NumericEditField
    end


    properties (Access = private)
        file_illuminated
        file_illuminated_path
        file_dark
        file_dark_path
  
        
         % Description
    end


    methods (Access = private)

        % Button pushed function: image_illuminatedButton
        function image_illuminatedButtonPushed(app, event)
            [app.file_illuminated, app.file_illuminated_path] = uigetfile;
%             [file,path] = uigetfile
%             [file,path,indx] = uigetfile
        end

        % Button pushed function: image_darkButton
        function image_darkButtonPushed(app, event)
            [app.file_dark,app.file_dark_path] = uigetfile;
        end

        % Button pushed function: calculateButton
        function calculateButtonPushed(app, event)
            width =  app.imagewidthEditField.Value;
            height = app.imageheightEditField.Value;
            
            RawBit = app.bitdepthEditField.Value;
            HeightPercent = app.heightpercentEditField.Value;
            WidthPercent = app.widthpercentEditField.Value;
            BayerOrder = app.bayerorderEditField.Value;
            

            fid = fopen([app.file_illuminated_path app.file_illuminated],'r');
            raw_illuminated = fread(fid,[width height],'ubit16=>uint16');
           % raw=raw_illuminated(1:width,:)';
            fclose(fid);
            fid = fopen([app.file_dark_path app.file_dark],'r');
            raw_dark = fread(fid,[width height],'ubit16=>uint16');
            raw = raw_illuminated - raw_dark;
            fclose(fid);
            raw=raw(1:width,:)';
      if strcmpi(BayerOrder,'rggb')
        Rmatrix = raw(1:2:end,1:2:end);
        GRmatrix = raw(1:2:end,2:2:end);
        GBmatrix = raw(2:2:end,1:2:end);
        Bmatrix = raw(2:2:end,2:2:end);
    elseif strcmpi(BayerOrder,'bggr')
        Bmatrix = raw(1:2:end,1:2:end);
        GBmatrix = raw(1:2:end,2:2:end);
        GRmatrix = raw(2:2:end,1:2:end);
        Rmatrix = raw(2:2:end,2:2:end);
    elseif strcmpi(BayerOrder,'bgrg')
        Bmatrix = raw(1:2:end,1:2:end);
        GBmatrix = raw(1:2:end,2:2:end);
        Rmatrix = raw(2:2:end,1:2:end);
        GRmatrix = raw(2:2:end,2:2:end);
    elseif strcmpi(BayerOrder,'grbg')
        GRmatrix = raw(1:2:end,1:2:end);
        Rmatrix = raw(1:2:end,2:2:end);
        Bmatrix = raw(2:2:end,1:2:end);
        GBmatrix = raw(2:2:end,2:2:end);
    end
        G_mean =  (GRmatrix + GRmatrix)./2;
        
        HeightBorder = round((1-HeightPercent/100)*height/4);
        WidthBorder = round((1-WidthPercent/100)*width/4);
          G_mean = G_mean(HeightBorder+1:end-HeightBorder,WidthBorder+1:end-WidthBorder);
          G_i = mean2(G_mean);
        app.sensitivityEditField.Value = G_i/(app.BEditField.Value*app.FSDEditField.Value/app.T_intEditField.Value);     
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.image_illuminatedButton = uibutton(app.UIFigure, 'push');
            app.image_illuminatedButton.ButtonPushedFcn = createCallbackFcn(app, @image_illuminatedButtonPushed, true);
            app.image_illuminatedButton.Text = 'image_illuminated';
            app.image_darkButton = uibutton(app.UIFigure, 'push');
            app.image_darkButton.ButtonPushedFcn = createCallbackFcn(app, @image_darkButtonPushed, true);
            app.image_darkButton.Text = 'image_dark';
            app.BEditFieldLabel = uilabel(app.UIFigure);
            app.BEditFieldLabel.Text = 'B';
            app.BEditField = uieditfield(app.UIFigure, 'numeric');
            app.BEditField.Value = 200;
            app.T_intEditFieldLabel = uilabel(app.UIFigure);
            app.T_intEditFieldLabel.Text = 'T_int';
            app.T_intEditField = uieditfield(app.UIFigure, 'numeric');
            app.T_intEditField.Value = 30;
            app.sensitivityEditFieldLabel = uilabel(app.UIFigure);
            app.sensitivityEditFieldLabel.Text = 'sensitivity';
            app.sensitivityEditField = uieditfield(app.UIFigure, 'numeric');
            app.sensitivityEditField.Editable = 'off';
            app.imagewidthEditFieldLabel = uilabel(app.UIFigure);
            app.imagewidthEditFieldLabel.Text = 'image width';
            app.imagewidthEditField = uieditfield(app.UIFigure, 'numeric');
            app.imagewidthEditField.Value = 1920;
            app.imageheightEditFieldLabel = uilabel(app.UIFigure);
            app.imageheightEditFieldLabel.Text = 'image height';
            app.imageheightEditField = uieditfield(app.UIFigure, 'numeric');
            app.imageheightEditField.Value = 1080;
            app.calculateButton = uibutton(app.UIFigure, 'push');
            app.calculateButton.ButtonPushedFcn = createCallbackFcn(app, @calculateButtonPushed, true);
            app.calculateButton.Text = 'calculate';
            app.bitdepthEditFieldLabel = uilabel(app.UIFigure);
            app.bitdepthEditFieldLabel.Text = 'bit depth';
            app.bitdepthEditField = uieditfield(app.UIFigure, 'text');
            app.bitdepthEditField.Value = '12';
            app.widthpercentEditFieldLabel = uilabel(app.UIFigure);
            app.widthpercentEditFieldLabel.Text = 'width percent';
            app.widthpercentEditField = uieditfield(app.UIFigure, 'numeric');
            app.widthpercentEditField.Value = 50;
            app.bayerorderEditFieldLabel = uilabel(app.UIFigure);
            app.bayerorderEditFieldLabel.Text = 'bayer order';
            app.bayerorderEditField = uieditfield(app.UIFigure, 'text');
            app.bayerorderEditField.Value = 'rggb';
            app.heightpercentEditFieldLabel = uilabel(app.UIFigure);
            app.heightpercentEditFieldLabel.Text = 'height percent';
            app.heightpercentEditField = uieditfield(app.UIFigure, 'numeric');
            app.heightpercentEditField.Value = 50;
            app.FSDEditFieldLabel = uilabel(app.UIFigure);
            app.FSDEditFieldLabel.Text = 'FSD';
            app.FSDEditField = uieditfield(app.UIFigure, 'numeric');
            app.FSDEditField.Value = 4095;
        end
    end

    methods (Access = public)

        % Construct app
        function app = sensitivity

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end