classdef sfr_child < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        SFR_RegionButtonGroup     matlab.ui.container.ButtonGroup
        ROIButton                 matlab.ui.control.RadioButton
        ALLButton                 matlab.ui.control.RadioButton
        WidthEditFieldLabel       matlab.ui.control.Label
        WidthEditField            matlab.ui.control.NumericEditField
        heightEditFieldLabel      matlab.ui.control.Label
        heightEditField           matlab.ui.control.NumericEditField
        Label                     matlab.ui.control.Label
        ListBox                   matlab.ui.control.ListBox
        ListBox1                  matlab.ui.control.ListBox
        blacklevelEditFieldLabel  matlab.ui.control.Label
        blacklevelEditField       matlab.ui.control.NumericEditField
        Select_imageButton        matlab.ui.control.Button
        ImageTypeDropDownLabel    matlab.ui.control.Label
        ImageTypeDropDown         matlab.ui.control.DropDown
    end


    properties (Access = private)
        file_path_name % Description
        main_handle_child
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, main_handle)
            %app.file_path_name = [main_handle.path main_handle.file];
            %app.main_handle_child = main_handle;
        end

        % Button pushed function: Select_imageButton
        function Select_imageButtonPushed(app, event)
width =  app.WidthEditField.Value;
height = app.heightEditField.Value;
black_level = app.blacklevelEditField.Value;

image_type = app.ImageTypeDropDown.Value;
SFR_region = app.SFR_RegionButtonGroup.SelectedObject.Value;
raw_type = app.ListBox.Value;
raw_oder = app.ListBox1.Value;

if strcmp(app.ImageTypeDropDown.Value,'raw')
     if (numel(width)<=0) || (numel(height)<=0)||(numel(black_level)<=0) 
         warndlg('please fill width & height & blacklevel firstly','warning');
         return;
     end
else
     if numel(black_level)<=0
         warndlg('please fill blacklevel firstly','warning');
         return;
     end
end

global PATH_NAME;
[filename, pathname, filterindex] = uigetfile( ...
       {'*.*',  'All Files (*.*)'}, ...
       'Pick a Image file', ...
        PATH_NAME);
if isequal(filename,0) || isequal(pathname,0)
    return;
end
PATH_NAME = pathname;

app.file_path_name = [pathname filename];
format=raw_type;
if strcmp(app.ImageTypeDropDown.Value,'raw')
    if(format == '1')
        frame=read_MIPI_8bit(app.file_path_name,width,height);
    end
    
    if(format == '2')
    FRAME=read_MIPI_10bit(app.file_path_name,width,height);%打开文件，读取数据，按8bit一个字节存储
    frame=convert_MIPI10toMIPI_8bit_vector(FRAME,width,height);
    end
    
    
    if(format== '3')
        FRAME=read_QBayer_10bit_file(app.file_path_name,width,height);
        frame=convert_QBayertoMIPI_8bit_vector(FRAME,width,height);
    end

    if(format == '4')
        frame=read_MIPI_plain(app.file_path_name,width,height);
    end 
    
    frame=frame-black_level;%减去blacklevel
    RGB=bilinear_process(frame, raw_oder, raw_type);%DEMOSIC 双线性插值
    image_Y=0.2989.*RGB(:,:,1)+0.5870.* RGB(:,:,2)+0.1140.*RGB(:,:,3);%RGB——>Y
else
    frame=imread(app.file_path_name);
    frame=frame-black_level;
    if numel(size(frame))==3
        image_Y=0.2989.*frame(:,:,1)+0.5870.* frame(:,:,2)+0.1140.*frame(:,:,3);%RGB——>Y
    else
        image_Y=frame;
    end
end
if SFR_region == 1  %ROI
    [image_roi,block_coor]=roi_extract(image_Y);%用户自己选择区域
    [sfr_4]=sfrmat(image_roi);
    block_width=block_coor(3)-block_coor(1);%计算选取的块的宽和高
    block_height=block_coor(4)-block_coor(2);
    if block_width>block_height%横
        sfr_four_vertical=[];
        sfr_four_horiz=sfr_4;%四分之一处的值
    else
        sfr_four_vertical=sfr_4;
        sfr_four_horiz=[];
    end
    %figure(2);
    %imshow(image_Y);
    %title('灰度图');
    %rectangle('Position',[block_coor(1),block_coor(2),block_width,block_height],'edgecolor','b');hold on;%标出ROI区域
    %text(block_coor(3)+50,block_coor(4),num2str(sfr_4),'Color','red');hold on;
    excel_size=1;
else%ALL
    %%纵向提取并计算
    [minY_vertical,sfr_four_vertical,sfr_three_vertical]=minblock(image_Y,1);%找到方块，并提取右边部分，块的大小可更改函数minblock第43~64行程序,1为纵向，0为横向
    % %%横向提取并计算
    [minY_horiz,sfr_four_horiz,sfr_three_horiz]=minblock(image_Y,0);%找到方块，并提取右边部分，块的大小可更改函数minblock第43~64行程序,1为纵向，0为横向
    excel_size_tmp=size(minY_horiz);
    excel_size=excel_size_tmp(2);
end
gcf=figure(1);
% scrsz = get(0,'ScreenSize');%放大图像
% set(gcf,'Position',scrsz);
hgexport(gcf, '-clipboard'); %复制图像到剪贴板
% F=getframe(gcf);%?//获取整个窗口内容的图像
% imwrite(F.cdata,'result.jpg'); %保存图像
% printexcel(sfr_four_vertical,sfr_four_horiz,excel_size);%把数据写入EXCEL            

%showRGB(RGB); 
%  R=RGB(:,:,1);
% G=RGB(:,:,2);
% B=RGB(:,:,3);
% [Y,U,V]=RGB2YUV(R,G,B);
% Y=histeq(Y,256);%直方图均衡
% [R,G,B]=YUV2RGB(Y,U,V);
% RGB(:,:,1)=R;
% RGB(:,:,2)=G;
% RGB(:,:,3)=B;
% showRGB(RGB); 

% save_frame_show=save_frame_show';
% RGB= demosaic(save_frame_show,'rggb');%用MATLAB自带去马赛克函数
% figure(2)
% imshow(RGB);

% --- Executes on selection change in raw_type.
         
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            value = app.ListBox.Value;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.SFR_RegionButtonGroup = uibuttongroup(app.UIFigure);
            app.SFR_RegionButtonGroup.Title = 'SFR_Region';
            app.ROIButton = uiradiobutton(app.SFR_RegionButtonGroup);
            app.ROIButton.Text = 'ROI';
            app.ROIButton.Value = true;
            app.ALLButton = uiradiobutton(app.SFR_RegionButtonGroup);
            app.ALLButton.Text = 'ALL';
            app.WidthEditFieldLabel = uilabel(app.UIFigure);
            app.WidthEditFieldLabel.Text = 'Width';
            app.WidthEditField = uieditfield(app.UIFigure, 'numeric');
            app.heightEditFieldLabel = uilabel(app.UIFigure);
            app.heightEditFieldLabel.Text = 'height';
            app.heightEditField = uieditfield(app.UIFigure, 'numeric');
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.ListBox = uilistbox(app.UIFigure);
            app.ListBox.Items = {'MIPI_8bit', 'MIPI_10bit', 'Plain_10bit', 'QualcommBayer'};
            app.ListBox.ItemsData = {'1', '2', '3', '4'};
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Value = '1';
            app.ListBox1 = uilistbox(app.UIFigure);
            app.ListBox1.Items = {'BGbGrR', 'RGrGbB', 'GbBRGr', 'GrRBGb'};
            app.ListBox1.ItemsData = {'1', '2', '3', '4'};
            app.ListBox1.Value = '1';
            app.blacklevelEditFieldLabel = uilabel(app.UIFigure);
            app.blacklevelEditFieldLabel.Text = 'black level';
            app.blacklevelEditField = uieditfield(app.UIFigure, 'numeric');
            app.Select_imageButton = uibutton(app.UIFigure, 'push');
            app.Select_imageButton.ButtonPushedFcn = createCallbackFcn(app, @Select_imageButtonPushed, true);
            app.Select_imageButton.Text = 'Select_image';
            app.ImageTypeDropDownLabel = uilabel(app.UIFigure);
            app.ImageTypeDropDownLabel.Text = 'Image Type';
            app.ImageTypeDropDown = uidropdown(app.UIFigure);
            app.ImageTypeDropDown.Items = {'raw', 'jpg', 'Option 3', 'Option 4'};
            app.ImageTypeDropDown.Value = 'raw';
        end
    end

    methods (Access = public)

        % Construct app
        function app = sfr_child(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end