classdef color_accuracy < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        widthEditFieldLabel            matlab.ui.control.Label
        widthEditField                 matlab.ui.control.NumericEditField
        heightEditFieldLabel           matlab.ui.control.Label
        heightEditField                matlab.ui.control.NumericEditField
        bitdepthDropDownLabel          matlab.ui.control.Label
        bitdepthDropDown               matlab.ui.control.DropDown
        blacklevel16bitEditFieldLabel  matlab.ui.control.Label
        blacklevel16bitEditField       matlab.ui.control.NumericEditField
        byteorderDropDownLabel         matlab.ui.control.Label
        byteorderDropDown              matlab.ui.control.DropDown
        bayernpatternDropDownLabel     matlab.ui.control.Label
        bayernpatternDropDown          matlab.ui.control.DropDown
        RAWSettingsLabel               matlab.ui.control.Label
        ColorSettingsLabel             matlab.ui.control.Label
        refdataDropDownLabel           matlab.ui.control.Label
        refdataDropDown                matlab.ui.control.DropDown
        colorspaceDropDownLabel        matlab.ui.control.Label
        colorspaceDropDown             matlab.ui.control.DropDown
        colordifferenceoptionDropDownLabel  matlab.ui.control.Label
        colordifferenceoptionDropDown  matlab.ui.control.DropDown
        loadRAWandtestButton           matlab.ui.control.Button
    end

    methods (Access = private)

        % Button pushed function: loadRAWandtestButton
        function loadRAWandtestButtonPushed(app, event)
            %raw settings
            width = app.widthEditField.Value;
            height = app.heightEditField.Value;
            bitDepth = app.bitdepthDropDown.Value;
            blackLevel = app.blacklevel16bitEditField.Value;
            byteOrder = app.byteorderDropDown.Value;
            pattern = app.bayernpatternDropDown.Value;
            %color settings
            refData = app.refdataDropDown.Value;
            colorSpace = app.colorspaceDropDown.Value;
            colorDiff = app.colordifferenceoptionDropDown.Value;
            
            [file,path] = uigetfile({'*.raw';'*.RAW'});
            if isequal(file,0)
                disp('User selected Cancel');
            else
                disp(['User selected ', fullfile(path,file)]);
            end
            
            if (file ~= 0)
                fid = fopen(fullfile(path,file));
                %settings
                if bitDepth == '16'
                    bit = 'uint16';
                elseif bitDepth == '8'
                    bit = 'uint8';
                end
                rawData = fread(fid,[width height],bit,byteOrder);
                fclose(fid);
                
                ci = demosaic(uint16(rawData' - blackLevel),pattern);% demosaic
                rgbImage = double(ci) * 16 ./ 65536;
                %simples white balance
                cors = colorChart24(rgbImage);
                close;
                gray22 = rgbImage(cors(4,1,1):cors(4,1,2),cors(4,1,3):cors(4,1,4),:,:);
                ratio_RG = mean2(gray22(:,:,1)) / mean2(gray22(:,:,2));
                ratio_BG = mean2(gray22(:,:,3)) / mean2(gray22(:,:,2));
                rgbImage(:,:,1) = rgbImage(:,:,1) * (1 / ratio_RG);
                rgbImage(:,:,3) = rgbImage(:,:,3) * (1 / ratio_BG);
                %color correction
                matrix =[1.5078 -0.4180 -0.0898;-0.2813 1.5508 -0.2695; 0.0742 -0.6133 1.5391];
                temp1 = reshape(rgbImage,[size(rgbImage,1) * size(rgbImage,2), size(rgbImage,3)]);
                temp = real(matrix * temp1');
                temp = temp .^ (1/2.2); %enable when display
                rgbImage = real(reshape(temp',[size(rgbImage,1) size(rgbImage,2), size(rgbImage,3)]));
                %imwrite(uint16(rgbImage * 65535),'D:\Development\AllinImaging\Process.tif');
                %figure,imshow(rgbImage);
                
                %get chart means
                means = zeros(4,6,3);
                s1 = cors(1,1,2) - cors(1,1,1) + 1;
                s2 = cors(1,1,4) - cors(1,1,3) + 1;
                for j = 1:4
                    for i = 1:6
                        pat = rgbImage(cors(j,i,1):cors(j,i,2),cors(j,i,3):cors(j,i,4),:);
                        tem1 = reshape(pat,s1*s2,3);
                        means(j,i,:) = mean(tem1);
                    end
                end
                means = means .^(2.2);%de-gamma
                srgb2xyz_d50 = [0.4361	0.3851	0.1431;0.2225	0.7169	0.0606;0.0139	0.0971	0.7141];
                xyz_50 = srgb2xyz_d50 * real(reshape(means,4*6,3)');%rgb to CIEXYZ conversion
                lab50 = wXYZ2LAB(xyz_50','D50');%CIEXYZ to CIELAB conversion
                lab50 = reshape(permute(reshape(lab50,[4,6,3]),[2,1,3]),[24,3]);
                cc_lab_d50 =[   37.9860	13.5550	14.0590;... 
                                65.7110	18.1300	17.8100;...
                                49.9270	-4.8800 -21.9250;...
                                43.1390	-13.0950 21.9050;...
                                55.1120	8.84400	-25.3990;...
                                70.7190	-33.3970 -0.1990;...
                                62.6610	36.0670	57.0960;...
                                40.0200	10.4100	-45.9640;...
                                51.1240	48.2390	16.2480;...
                                30.3250	22.9760	-21.5870;...
                                72.5320	-23.7090 57.2550;...
                                71.9410	19.3630	67.8570;...
                                28.7780	14.1790	-50.2970;...
                                55.2610	-38.3420 31.3700;...
                                42.1010	53.3780	28.1900;...
                                81.7330	4.0390	79.8190;...
                                51.9350	49.9860	-14.5740;...
                                51.0380	-28.6310 -28.6380;...
                                96.5390	-0.4250 1.1860;...
                                81.2570	-0.6380 -0.3350;...
                                66.7660	-0.7340 -0.50400;...
                                50.8670	-0.1530 -0.27000;...
                                35.6560	-0.4210 -1.2310;...
                                20.4610	-0.0790 -0.9730];
                
                de76 = DeltaE1976(lab50,cc_lab_d50);%color differences, deltaE,deltaL,deltaA,deltaB,deltaC
                assignin('base','de1976',de76);
                disp('Color accuracy analyze end.');
                disp('5 colomn in de1976: DeltaE, DeltaL, DeltaA, DeltaB, DeltaC');
            end
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.widthEditFieldLabel = uilabel(app.UIFigure);
            app.widthEditFieldLabel.Text = 'width';
            app.widthEditField = uieditfield(app.UIFigure, 'numeric');
            app.widthEditField.Limits = [0 Inf];
            app.widthEditField.ValueDisplayFormat = '%.0f';
            app.widthEditField.Value = 1920;
            app.heightEditFieldLabel = uilabel(app.UIFigure);
            app.heightEditFieldLabel.Text = 'height';
            app.heightEditField = uieditfield(app.UIFigure, 'numeric');
            app.heightEditField.Limits = [0 Inf];
            app.heightEditField.ValueDisplayFormat = '%.0f';
            app.heightEditField.Value = 1080;
            app.bitdepthDropDownLabel = uilabel(app.UIFigure);
            app.bitdepthDropDownLabel.Text = 'bit depth';
            app.bitdepthDropDown = uidropdown(app.UIFigure);
            app.bitdepthDropDown.Items = {'8', '16'};
            app.bitdepthDropDown.Value = '16';
            app.blacklevel16bitEditFieldLabel = uilabel(app.UIFigure);
            app.blacklevel16bitEditFieldLabel.Text = 'black level(16 bit)';
            app.blacklevel16bitEditField = uieditfield(app.UIFigure, 'numeric');
            app.blacklevel16bitEditField.ValueDisplayFormat = '%.0f';
            app.blacklevel16bitEditField.Value = 240;
            app.byteorderDropDownLabel = uilabel(app.UIFigure);
            app.byteorderDropDownLabel.Text = 'byte order';
            app.byteorderDropDown = uidropdown(app.UIFigure);
            app.byteorderDropDown.Items = {'ieee-le', 'ieee-be'};
            app.byteorderDropDown.Value = 'ieee-le';
            app.bayernpatternDropDownLabel = uilabel(app.UIFigure);
            app.bayernpatternDropDownLabel.Text = 'bayern pattern';
            app.bayernpatternDropDown = uidropdown(app.UIFigure);
            app.bayernpatternDropDown.Items = {'RGGB', 'GRBG', 'GBRG', 'BGGR'};
            app.bayernpatternDropDown.Value = 'RGGB';
            app.RAWSettingsLabel = uilabel(app.UIFigure);
            app.RAWSettingsLabel.Text = 'RAW Settings:';
            app.ColorSettingsLabel = uilabel(app.UIFigure);
            app.ColorSettingsLabel.Text = 'Color Settings:';
            app.refdataDropDownLabel = uilabel(app.UIFigure);
            app.refdataDropDownLabel.Text = 'ref data';
            app.refdataDropDown = uidropdown(app.UIFigure);
            app.refdataDropDown.Items = {'Xrite published (default)', 'Customer'};
            app.refdataDropDown.Value = 'Xrite published (default)';
            app.colorspaceDropDownLabel = uilabel(app.UIFigure);
            app.colorspaceDropDownLabel.Text = 'color space';
            app.colorspaceDropDown = uidropdown(app.UIFigure);
            app.colorspaceDropDown.Items = {'sRGB', 'Adobe RGB', 'display P3(Apple)', 'DCI-P3'};
            app.colorspaceDropDown.Value = 'sRGB';
            app.colordifferenceoptionDropDownLabel = uilabel(app.UIFigure);
            app.colordifferenceoptionDropDownLabel.Text = 'color difference option';
            app.colordifferenceoptionDropDown = uidropdown(app.UIFigure);
            app.colordifferenceoptionDropDown.Items = {'CIE 1976', 'CIE1994', 'DE2000'};
            app.colordifferenceoptionDropDown.Value = 'CIE 1976';
            app.loadRAWandtestButton = uibutton(app.UIFigure, 'push');
            app.loadRAWandtestButton.ButtonPushedFcn = createCallbackFcn(app, @loadRAWandtestButtonPushed, true);
            app.loadRAWandtestButton.Text = 'load RAW and test';
        end
    end

    methods (Access = public)

        % Construct app
        function app = color_accuracy

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end