classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        allincamtoolboxUIFigure  matlab.ui.Figure
        TabGroup                 matlab.ui.container.TabGroup
        raw_viewerTab            matlab.ui.container.Tab
        openrawButton            matlab.ui.control.Button
        rawformatDropDownLabel   matlab.ui.control.Label
        rawformatDropDown        matlab.ui.control.DropDown
        sensorTab                matlab.ui.container.Tab
        quantumefficiencyButton  matlab.ui.control.Button
        wellcapacityButton       matlab.ui.control.Button
        dynamicrangeButton       matlab.ui.control.Button
        fpnButton                matlab.ui.control.Button
        blackreferenceButton     matlab.ui.control.Button
        PTCButton                matlab.ui.control.Button
        noiseoutButton           matlab.ui.control.Button
        temporalnoiseButton      matlab.ui.control.Button
        shadingButton            matlab.ui.control.Button
        DSNUButton               matlab.ui.control.Button
        sensitivityButton        matlab.ui.control.Button
        F2FvarianceButton        matlab.ui.control.Button
        PRNUButton               matlab.ui.control.Button
        MRNLButton               matlab.ui.control.Button
        PSSRButton               matlab.ui.control.Button
        noisemodelButton         matlab.ui.control.Button
        illuminationButton       matlab.ui.control.Button
        blemishButton            matlab.ui.control.Button
        defectpixelButton        matlab.ui.control.Button
        greenimbalanceButton     matlab.ui.control.Button
        coloraccuracyButton      matlab.ui.control.Button
        POLButton                matlab.ui.control.Button
        fillfactorButton         matlab.ui.control.Button
        snrButton                matlab.ui.control.Button
        framerateButton          matlab.ui.control.Button
        opticsTab                matlab.ui.container.Tab
        pixelprofileButton       matlab.ui.control.Button
        vignetteButton           matlab.ui.control.Button
        colorshadingButton       matlab.ui.control.Button
        fisheyeshadingButton     matlab.ui.control.Button
        SlantSFRButton           matlab.ui.control.Button
        colormoireButton         matlab.ui.control.Button
        edgeroughnessButton      matlab.ui.control.Button
        NPSSFRButton             matlab.ui.control.Button
        circleSFRButton          matlab.ui.control.Button
        wedgeSFRButton           matlab.ui.control.Button
        veilingglareButton       matlab.ui.control.Button
        opticalcenterButton      matlab.ui.control.Button
        fovButton                matlab.ui.control.Button
        distortionButton         matlab.ui.control.Button
        sharpnessButton          matlab.ui.control.Button
        CAButton                 matlab.ui.control.Button
        localblurButton          matlab.ui.control.Button
        photographyTab           matlab.ui.container.Tab
        ISOButton                matlab.ui.control.Button
        exposureerrorButton      matlab.ui.control.Button
        resolutionButton         matlab.ui.control.Button
        textureButton            matlab.ui.control.Button
        dynamicrangeButton_2     matlab.ui.control.Button
        whitebalanceButton       matlab.ui.control.Button
        coloraccuracyButton_2    matlab.ui.control.Button
        visualnoiseButton        matlab.ui.control.Button
        noiseButton              matlab.ui.control.Button
        contrastButton           matlab.ui.control.Button
        autofocusButton          matlab.ui.control.Button
        lagsButton               matlab.ui.control.Button
        mechanicsTab             matlab.ui.container.Tab
        vcmButton                matlab.ui.control.Button
        oisButton                matlab.ui.control.Button
        ATab                     matlab.ui.container.Tab
        AEButton                 matlab.ui.control.Button
        AWBButton                matlab.ui.control.Button
        AFButton                 matlab.ui.control.Button
        sw_ispTab                matlab.ui.container.Tab
        ispengineDropDownLabel   matlab.ui.control.Label
        ispengineDropDown        matlab.ui.control.DropDown
        openfileButton           matlab.ui.control.Button
        exifTab                  matlab.ui.container.Tab
        openfileButton_2         matlab.ui.control.Button
        converterTab             matlab.ui.container.Tab
        Raw2DNGButton            matlab.ui.control.Button
        Raw2Plain16Button        matlab.ui.control.Button
        rawtypeDropDownLabel     matlab.ui.control.Label
        rawtypeDropDown          matlab.ui.control.DropDown
    end


    properties (Access = public)
        file % Description
        path
    end


    methods (Access = private)

        % Button pushed function: openrawButton
        function openrawButtonPushed(app, event)
  app.openrawButton.Enable = 'off';          
  [app.file,app.path] = uigetfile({'*.raw';'*.RAW'});
if isequal(app.file,0)
   disp('User selected Cancel');
else
   disp(['User selected ', fullfile(app.path,app.file)]);
end
if(app.file ~= 0 ) 
  
raw_child1(app);
end
        end

        % Button pushed function: Raw2Plain16Button
        function Raw2Plain16ButtonPushed(app, event)

app.Raw2Plain16Button.Enable = 'off';

  
raw2plain16(app);
        end

        % Button pushed function: SlantSFRButton
        function SlantSFRButtonPushed(app, event)
            sfr_child(app);
        end

        % Button pushed function: blackreferenceButton
        function blackreferenceButtonPushed(app, event)
              black_reference(app); 
        end

        % Button pushed function: sensitivityButton
        function sensitivityButtonPushed(app, event)
             sensitivity();
        end

        % Button pushed function: illuminationButton
        function illuminationButtonPushed(app, event)
            illumination();
        end

        % Button pushed function: Raw2DNGButton
        function Raw2DNGButtonPushed(app, event)
            
        end

        % Button pushed function: POLButton
        function POLButtonPushed(app, event)
            perc_linearity(app);
        end

        % Button pushed function: coloraccuracyButton
        function coloraccuracyButtonPushed(app, event)
            app.coloraccuracyButton.Enable = 'off';
            color_accuracy();
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.allincamtoolboxUIFigure = uifigure;
            app.allincamtoolboxUIFigure.Name = 'allincam toolbox';
            app.TabGroup = uitabgroup(app.allincamtoolboxUIFigure);
            app.raw_viewerTab = uitab(app.TabGroup);
            app.raw_viewerTab.Title = 'raw_viewer';
            app.openrawButton = uibutton(app.raw_viewerTab, 'push');
            app.openrawButton.ButtonPushedFcn = createCallbackFcn(app, @openrawButtonPushed, true);
            app.openrawButton.Text = 'open raw';
            app.rawformatDropDownLabel = uilabel(app.raw_viewerTab);
            app.rawformatDropDownLabel.Text = 'raw format';
            app.rawformatDropDown = uidropdown(app.raw_viewerTab);
            app.rawformatDropDown.Items = {'customer_raw', 'raw_plain_16', 'mipi_raw10', 'mipi_raw8'};
            app.rawformatDropDown.Value = 'raw_plain_16';
            app.sensorTab = uitab(app.TabGroup);
            app.sensorTab.Title = 'sensor';
            app.quantumefficiencyButton = uibutton(app.sensorTab, 'push');
            app.quantumefficiencyButton.Text = 'quantum efficiency';
            app.wellcapacityButton = uibutton(app.sensorTab, 'push');
            app.wellcapacityButton.Text = 'well capacity';
            app.dynamicrangeButton = uibutton(app.sensorTab, 'push');
            app.dynamicrangeButton.Text = 'dynamic range';
            app.fpnButton = uibutton(app.sensorTab, 'push');
            app.fpnButton.Text = 'fpn';
            app.blackreferenceButton = uibutton(app.sensorTab, 'push');
            app.blackreferenceButton.ButtonPushedFcn = createCallbackFcn(app, @blackreferenceButtonPushed, true);
            app.blackreferenceButton.Text = 'black reference';
            app.PTCButton = uibutton(app.sensorTab, 'push');
            app.PTCButton.Text = 'PTC';
            app.noiseoutButton = uibutton(app.sensorTab, 'push');
            app.noiseoutButton.Text = 'noise out';
            app.temporalnoiseButton = uibutton(app.sensorTab, 'push');
            app.temporalnoiseButton.Text = 'temporal noise';
            app.shadingButton = uibutton(app.sensorTab, 'push');
            app.shadingButton.Text = 'shading';
            app.DSNUButton = uibutton(app.sensorTab, 'push');
            app.DSNUButton.Text = 'DSNU';
            app.sensitivityButton = uibutton(app.sensorTab, 'push');
            app.sensitivityButton.ButtonPushedFcn = createCallbackFcn(app, @sensitivityButtonPushed, true);
            app.sensitivityButton.Text = 'sensitivity';
            app.F2FvarianceButton = uibutton(app.sensorTab, 'push');
            app.F2FvarianceButton.Text = 'F2F variance';
            app.PRNUButton = uibutton(app.sensorTab, 'push');
            app.PRNUButton.Text = 'PRNU';
            app.MRNLButton = uibutton(app.sensorTab, 'push');
            app.MRNLButton.Text = 'MRNL';
            app.PSSRButton = uibutton(app.sensorTab, 'push');
            app.PSSRButton.Text = 'PSSR';
            app.noisemodelButton = uibutton(app.sensorTab, 'push');
            app.noisemodelButton.Text = 'noise model';
            app.illuminationButton = uibutton(app.sensorTab, 'push');
            app.illuminationButton.ButtonPushedFcn = createCallbackFcn(app, @illuminationButtonPushed, true);
            app.illuminationButton.Text = 'illumination';
            app.blemishButton = uibutton(app.sensorTab, 'push');
            app.blemishButton.Text = 'blemish';
            app.defectpixelButton = uibutton(app.sensorTab, 'push');
            app.defectpixelButton.Text = 'defect pixel';
            app.greenimbalanceButton = uibutton(app.sensorTab, 'push');
            app.greenimbalanceButton.Text = 'green imbalance';
            app.coloraccuracyButton = uibutton(app.sensorTab, 'push');
            app.coloraccuracyButton.ButtonPushedFcn = createCallbackFcn(app, @coloraccuracyButtonPushed, true);
            app.coloraccuracyButton.Text = 'color accuracy';
            app.POLButton = uibutton(app.sensorTab, 'push');
            app.POLButton.ButtonPushedFcn = createCallbackFcn(app, @POLButtonPushed, true);
            app.POLButton.Text = 'POL';
            app.fillfactorButton = uibutton(app.sensorTab, 'push');
            app.fillfactorButton.Text = 'fill factor';
            app.snrButton = uibutton(app.sensorTab, 'push');
            app.snrButton.Text = 'snr';
            app.framerateButton = uibutton(app.sensorTab, 'push');
            app.framerateButton.Text = 'frame rate';
            app.opticsTab = uitab(app.TabGroup);
            app.opticsTab.Title = 'optics';
            app.pixelprofileButton = uibutton(app.opticsTab, 'push');
            app.pixelprofileButton.Text = 'pixel profile';
            app.vignetteButton = uibutton(app.opticsTab, 'push');
            app.vignetteButton.Text = 'vignette';
            app.colorshadingButton = uibutton(app.opticsTab, 'push');
            app.colorshadingButton.Text = 'color shading';
            app.fisheyeshadingButton = uibutton(app.opticsTab, 'push');
            app.fisheyeshadingButton.Text = 'fish eye shading';
            app.SlantSFRButton = uibutton(app.opticsTab, 'push');
            app.SlantSFRButton.ButtonPushedFcn = createCallbackFcn(app, @SlantSFRButtonPushed, true);
            app.SlantSFRButton.Text = 'Slant SFR';
            app.colormoireButton = uibutton(app.opticsTab, 'push');
            app.colormoireButton.Text = 'color moire';
            app.edgeroughnessButton = uibutton(app.opticsTab, 'push');
            app.edgeroughnessButton.Text = 'edge roughness';
            app.NPSSFRButton = uibutton(app.opticsTab, 'push');
            app.NPSSFRButton.Text = 'NPS SFR';
            app.circleSFRButton = uibutton(app.opticsTab, 'push');
            app.circleSFRButton.Text = 'circle SFR';
            app.wedgeSFRButton = uibutton(app.opticsTab, 'push');
            app.wedgeSFRButton.Text = 'wedge SFR';
            app.veilingglareButton = uibutton(app.opticsTab, 'push');
            app.veilingglareButton.Text = 'veiling glare';
            app.opticalcenterButton = uibutton(app.opticsTab, 'push');
            app.opticalcenterButton.Text = 'optical center';
            app.fovButton = uibutton(app.opticsTab, 'push');
            app.fovButton.Text = 'fov';
            app.distortionButton = uibutton(app.opticsTab, 'push');
            app.distortionButton.Text = 'distortion';
            app.sharpnessButton = uibutton(app.opticsTab, 'push');
            app.sharpnessButton.Text = 'sharpness';
            app.CAButton = uibutton(app.opticsTab, 'push');
            app.CAButton.Text = 'CA';
            app.localblurButton = uibutton(app.opticsTab, 'push');
            app.localblurButton.Text = 'local blur';
            app.photographyTab = uitab(app.TabGroup);
            app.photographyTab.Title = 'photography';
            app.ISOButton = uibutton(app.photographyTab, 'push');
            app.ISOButton.Text = 'ISO';
            app.exposureerrorButton = uibutton(app.photographyTab, 'push');
            app.exposureerrorButton.Text = 'exposure error';
            app.resolutionButton = uibutton(app.photographyTab, 'push');
            app.resolutionButton.Text = 'resolution';
            app.textureButton = uibutton(app.photographyTab, 'push');
            app.textureButton.Text = 'texture';
            app.dynamicrangeButton_2 = uibutton(app.photographyTab, 'push');
            app.dynamicrangeButton_2.Text = 'dynamic range';
            app.whitebalanceButton = uibutton(app.photographyTab, 'push');
            app.whitebalanceButton.Text = 'white balance';
            app.coloraccuracyButton_2 = uibutton(app.photographyTab, 'push');
            app.coloraccuracyButton_2.Text = 'color accuracy';
            app.visualnoiseButton = uibutton(app.photographyTab, 'push');
            app.visualnoiseButton.Text = 'visual noise';
            app.noiseButton = uibutton(app.photographyTab, 'push');
            app.noiseButton.Text = 'noise';
            app.contrastButton = uibutton(app.photographyTab, 'push');
            app.contrastButton.Text = 'contrast';
            app.autofocusButton = uibutton(app.photographyTab, 'push');
            app.autofocusButton.Text = 'auto focus';
            app.lagsButton = uibutton(app.photographyTab, 'push');
            app.lagsButton.Text = 'lags';
            app.mechanicsTab = uitab(app.TabGroup);
            app.mechanicsTab.Title = 'mechanics';
            app.vcmButton = uibutton(app.mechanicsTab, 'push');
            app.vcmButton.Text = 'vcm';
            app.oisButton = uibutton(app.mechanicsTab, 'push');
            app.oisButton.Text = 'ois';
            app.ATab = uitab(app.TabGroup);
            app.ATab.Title = '3A';
            app.AEButton = uibutton(app.ATab, 'push');
            app.AEButton.Text = 'AE';
            app.AWBButton = uibutton(app.ATab, 'push');
            app.AWBButton.Text = 'AWB';
            app.AFButton = uibutton(app.ATab, 'push');
            app.AFButton.Text = 'AF';
            app.sw_ispTab = uitab(app.TabGroup);
            app.sw_ispTab.Title = 'sw_isp';
            app.ispengineDropDownLabel = uilabel(app.sw_ispTab);
            app.ispengineDropDownLabel.Text = 'isp engine';
            app.ispengineDropDown = uidropdown(app.sw_ispTab);
            app.ispengineDropDown.Items = {'simple pipeline', 'dc raw', 'allincam isp', 'raw plain 16'};
            app.ispengineDropDown.Value = 'simple pipeline';
            app.openfileButton = uibutton(app.sw_ispTab, 'push');
            app.openfileButton.Text = 'open file';
            app.exifTab = uitab(app.TabGroup);
            app.exifTab.Title = 'exif';
            app.openfileButton_2 = uibutton(app.exifTab, 'push');
            app.openfileButton_2.Text = 'open file';
            app.converterTab = uitab(app.TabGroup);
            app.converterTab.Title = 'converter';
            app.Raw2DNGButton = uibutton(app.converterTab, 'push');
            app.Raw2DNGButton.ButtonPushedFcn = createCallbackFcn(app, @Raw2DNGButtonPushed, true);
            app.Raw2DNGButton.Text = 'Raw2DNG';
            app.Raw2Plain16Button = uibutton(app.converterTab, 'push');
            app.Raw2Plain16Button.ButtonPushedFcn = createCallbackFcn(app, @Raw2Plain16ButtonPushed, true);
            app.Raw2Plain16Button.Text = 'Raw2Plain16';
            app.rawtypeDropDownLabel = uilabel(app.converterTab);
            app.rawtypeDropDownLabel.Text = 'raw type';
            app.rawtypeDropDown = uidropdown(app.converterTab);
            app.rawtypeDropDown.Items = {'customer_raw', 'mipi_raw_10', 'mipi_raw8', 'raw_plain_16'};
            app.rawtypeDropDown.Value = 'customer_raw';
        end
    end

    methods (Access = public)

        % Construct app
        function app = main

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.allincamtoolboxUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.allincamtoolboxUIFigure)
        end
    end
end